classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Subeunarchivoconlanubedepuntosdetumodelo3dLabel  matlab.ui.control.Label
        SubirarchivoButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        state
        grid
        plot_axis
        panel
        selector_label
        selector
        next
        previous
        radio_selector
        radio_label
        results_label
        new_measure
        measureObject Object3D
        resumeSource
        alpha_slider
        alpha_slider_label
        get_area_button
        get_volume_button
        get_region_button
        get_volume_region_button
    end

    methods (Access = private)
        
        function onNextPushed(app, ~)
            import utils.selectPoints
            import utils.points2str
            import interpolate.InterpolateCurve
    
            try
                % STATE: DROPDOWN
                if strcmp(app.state,'dropdown')
                    app.previous.Enable = 'on';
                    app.selector.Enable = 'off';
    
                    % create selector 
                    app.radio_selector = uibuttongroup(app.panel, "BackgroundColor", "White", "BorderType","none");
                    app.radio_selector.Position = [60 465 490 200];
                    app.radio_label = uilabel(app.radio_selector, "Text", "De que forma desea hacer la medicion?", "Position", [5 175 300 25], "FontSize",14);
                    if strcmp(app.selector.Value,'Volumen')
                        app.state = 'volumen';
                        b1 = uiradiobutton(app.radio_selector, "Text", "Calcular el volumen de toda la figura",'Position',[5 150 300 25], "FontSize",14);
                        b2 = uiradiobutton(app.radio_selector, "Text", "Calcular el volumen de una region acotada por un cubo",'Position',[5 125 500 25], "FontSize",14);
                    elseif strcmp(app.selector.Value,'Longitud')
                        app.state = 'longitud';
                        b1 = uiradiobutton(app.radio_selector, "Text", "Seleccionando los puntos manualmente",'Position',[5 150 300 25], "FontSize",14);
                        b2 = uiradiobutton(app.radio_selector, "Text", "Dibujando una curva sobre la figura",'Position',[5 125 300 25], "FontSize",14);
                    elseif strcmp(app.selector.Value,'Area')
                        app.state = 'area';
                        b1 = uiradiobutton(app.radio_selector, "Text", "Calcular el area de toda la figura",'Position',[5 100 300 25], "FontSize",14);
                        b2 = uiradiobutton(app.radio_selector, "Text", "Calcular el area de una region acotada por un cubo",'Position',[5 150 500 25], "FontSize",14);
                        b3 = uiradiobutton(app.radio_selector, "Text", "Dibujando una region sobre la figura",'Position',[5 125 300 25], "FontSize",14);
                    end

                % STATE: MEASURE VOLUME
                elseif strcmp(app.state, 'volumen')

                    % total volume
                    if strcmp(app.radio_selector.SelectedObject.Text, 'Calcular el volumen de toda la figura')
                        % delete or hide panel elements
                        delete(app.radio_selector.Children)
                        delete(app.radio_selector)
                        app.selector.Visible = 'off';
                        app.selector_label.Visible = 'off';
                        app.next.Visible = 'off';
                        app.previous.Visible = 'off';
                        focus(app.UIFigure)

                        % create slider for alpha radiuses
                        format shortG                 
                        app.alpha_slider = uislider(app.panel, "Position", [125 490 350 200]);
                        app.alpha_slider.MinorTicks = [];
                        alpha_min = app.measureObject.alpha_range(1);
                        alpha_max = app.measureObject.alpha_range(2);
                        app.alpha_slider.Limits = [alpha_min, alpha_max];
                        app.alpha_slider.Value = alpha_min;
                        app.alpha_slider.ValueChangedFcn = @(src,event)updateSlider(app, event);

                        app.alpha_slider_label = uilabel(app.panel, ...
                            "Text", "Mueva el slider para ajustar la concavidad/convexidad del solido asociado a la nube de puntos. Asegurese que no queden triangulos al interior del solido, de lo contrario las mediciones se veran alteradas.", ...
                            "Position", [60 505 490 150], "WordWrap","on", "FontSize", 14);

                        % add button for new measure/results
                        app.get_volume_button = uibutton(app.panel, 'push');
                        app.get_volume_button.Text = 'Obtener resultados y hacer una nueva medicion';
                        app.get_volume_button.Position = [113 375 370 30];
                        app.get_volume_button.ButtonPushedFcn = createCallbackFcn(app, @(app, ~) onGetVolumePushed(app), true);

                    % volume bounded by cubic region
                    elseif strcmp(app.radio_selector.SelectedObject.Text, 'Calcular el volumen de una region acotada por un cubo')
                        % delete or hide panel elements
                        delete(app.radio_selector.Children)
                        delete(app.radio_selector)
                        app.selector.Visible = 'off';
                        app.selector_label.Visible = 'off';
                        app.next.Visible = 'off';
                        app.previous.Visible = 'off';
                        focus(app.UIFigure)

                        % create slider for alpha radiuses
                        format shortG                 
                        app.alpha_slider = uislider(app.panel, "Position", [125 490 350 200]);
                        app.alpha_slider.MinorTicks = [];
                        alpha_min = app.measureObject.alpha_range(1);
                        alpha_max = app.measureObject.alpha_range(2);
                        app.alpha_slider.Limits = [alpha_min, alpha_max];
                        app.alpha_slider.Value = alpha_min;
                        app.alpha_slider.ValueChangedFcn = @(src,event)updateSlider(app, event);

                        app.alpha_slider_label = uilabel(app.panel, ...
                            "Text", "Mueva el slider para ajustar la concavidad/convexidad del solido asociado a la nube de puntos. Asegurese que no queden triangulos al interior del solido, de lo contrario las mediciones se veran alteradas.", ...
                            "Position", [60 505 490 150], "WordWrap","on", "FontSize", 14);

                        % add button
                        app.get_region_button = uibutton(app.panel, 'push');
                        app.get_region_button.Text = 'Seleccionar region de interes';
                        app.get_region_button.Position = [173 375 250 30];
                        app.get_region_button.ButtonPushedFcn = createCallbackFcn(app, @(app, ~) onGetRegionPushed(app, @onGetVolumeRegionPushed), true);
                    end

                % STATE: MEASURE LENGTH
                elseif strcmp(app.state, 'longitud')
    
                    % manual selection
                    if strcmp(app.radio_selector.SelectedObject.Text, 'Seleccionando los puntos manualmente')
                        % asks for splines deegre
                        ans_suavidad = uiconfirm(app.UIFigure, ...
                            "Desea que los puntos sean unidos con una linea recta o con una curva suave?", ...
                            "Suavidad de la curva", ...
                            "Options",["Suave","Recta"], ...
                            "DefaultOption",2);
                        if strcmp(ans_suavidad, 'Suave')
                            deegre = 'cubic';
                        elseif strcmp(ans_suavidad, 'Recta')
                            deegre = 'linear';
                        end

                        % informs user
                        ans = uiconfirm(app.UIFigure, ...
                            "Seleccione los puntos haciendo click en ellos. Cuando halla finalizado presione cualquier tecla.", ...
                            "Seleccione los puntos", ...
                            "Options","OK", "Icon","info");
                        
                        % delete or hide panel elements
                        delete(app.radio_selector.Children)
                        delete(app.radio_selector)
                        app.selector.Visible = 'off';
                        app.selector_label.Visible = 'off';
                        app.next.Visible = 'off';
                        app.previous.Visible = 'off';
                        focus(app.UIFigure)
    
                        % disable all cursor modes and hide toolbar (they
                        % conflict with ButtonDown callback)
                        rotate3d(app.plot_axis, 'off')
                        brush(app.plot_axis, 'off')
                        datacursormode(app.plot_axis, 'off')
                        pan(app.plot_axis, 'off')
                        zoom(app.plot_axis, 'off')
                        app.plot_axis.Toolbar.Visible = 'off';
    
                        % callbacks: ButtonDown for selecting point, KeyPress
                        % to end selection
                        % nextHandle selects new point, it waits until one
                        % of the callbacks resumes execution 
                        nextHandle = selectPoints(app);                
                        app.plot_axis.Children.ButtonDownFcn = @(src,~)onButtonDown(app);
                        app.UIFigure.WindowKeyPressFcn = @(src,~)onKeyPress(app);
                        
                        app.results_label = uilabel(app.panel,"Text","Seleccionando puntos...", "Position", [60 475 490 530], "FontSize", 14);
                        points_plot = [];
                        while true 
                            [points, ended] = nextHandle();
                            app.results_label.Text = [{'Seleccionando puntos...'}; {'Puntos seleccionados:'}; points2str(points)]; 
                            app.results_label.Position(1, 2) = app.results_label.Position(1, 2)-9;
                            if ended
                                app.results_label.Text = [app.results_label.Text; {'Seleccion finalizada'}];
                                app.results_label.Position(1, 2) = app.results_label.Position(1, 2)-9;
                                delete(points_plot)
                               break
                            end
                            delete(points_plot)
                            points_plot = plot3(app.plot_axis, points(:, 1), points(:, 2), points(:, 3), '.', 'Color', [0.8500 0.3250 0.0980], 'MarkerSize',20);
                            points_plot.ButtonDownFcn = @(src,~)onButtonDown(app);
                        end
                        % delete callbacks
                        app.plot_axis.Children.ButtonDownFcn = '';
                        app.UIFigure.WindowKeyPressFcn = '';
    
                        if strcmp(deegre, 'cubic')
                            deegre_label = 'cubicos';
                        elseif strcmp(deegre, 'linear')
                            deegre_label = 'lineales';
                        end
                        if size(points, 1) > 1
                            app.results_label.Text = [app.results_label.Text; {char(['Interpolando con splines ', deegre_label, '...'])}];
                            app.results_label.Position(1, 2) = app.results_label.Position(1, 2)-9;
                            pause(0.1) % if pauses are removed labels won't update until all calculations are done
                            curve = InterpolateCurve(points, 1, size(points, 1), "deegre", deegre); 
                            curve.show(app.plot_axis)
                            pause(0.1)
                            app.results_label.Text = [app.results_label.Text; {'Calculando integral de longitud de arco...'}]; 
                            app.results_label.Position(1, 2) = app.results_label.Position(1, 2)-9;
                            pause(0.1)
                            length = app.measureObject.lengthCurve(points, deegre); % possible optimization: use curve, not points
                        else
                            length = 0;
                        end
                        app.results_label.Text = [app.results_label.Text; {'Resultado:'}; {char(['La curva mide ',num2str(length),' unidades'])}];
                        app.results_label.Position(1, 2) = app.results_label.Position(1, 2)-9;
    
                        % Add 'new_measure' button
                        app.new_measure = uibutton(app.panel, 'push');
                        app.new_measure.Text = 'Nueva medicion';
                        app.new_measure.Position = [185 100 230 30];
                        app.new_measure.ButtonPushedFcn = createCallbackFcn(app, @onNewMeasurePushed, true);

                        app.plot_axis.Toolbar.Visible = 'on';
                        
                    
                    elseif strcmp(app.radio_selector.SelectedObject.Text, 'Dibujando una curva sobre la figura')
                        % not implemented: see issue #40
                    end

                % STATE: MEASURE AREA
                elseif strcmp(app.state, 'area')

                    % manual selection
                    if strcmp(app.radio_selector.SelectedObject.Text, 'Calcular el area de una region acotada por un cubo')
                        % delete or hide panel elements
                        delete(app.radio_selector.Children)
                        delete(app.radio_selector)
                        app.selector.Visible = 'off';
                        app.selector_label.Visible = 'off';
                        app.next.Visible = 'off';
                        app.previous.Visible = 'off';
                        focus(app.UIFigure)

                        % create slider for alpha radiuses
                        format shortG                 
                        app.alpha_slider = uislider(app.panel, "Position", [125 490 350 200]);
                        app.alpha_slider.MinorTicks = [];
                        alpha_min = app.measureObject.alpha_range(1);
                        alpha_max = app.measureObject.alpha_range(2);
                        app.alpha_slider.Limits = [alpha_min, alpha_max];
                        app.alpha_slider.Value = alpha_min;
                        app.alpha_slider.ValueChangedFcn = @(src,event)updateSlider(app, event);

                        app.alpha_slider_label = uilabel(app.panel, ...
                            "Text", "Mueva el slider para ajustar la concavidad/convexidad del solido asociado a la nube de puntos. Asegurese que no queden triangulos al interior del solido, de lo contrario las mediciones se veran alteradas.", ...
                            "Position", [60 505 490 150], "WordWrap","on", "FontSize", 14);

                        % add button
                        app.get_region_button = uibutton(app.panel, 'push');
                        app.get_region_button.Text = 'Seleccionar region de interes';
                        app.get_region_button.Position = [173 375 250 30];
                        app.get_region_button.ButtonPushedFcn = createCallbackFcn(app, @(app, ~) onGetRegionPushed(app, @onGetAreaRegionPushed), true);
                    
                    elseif strcmp(app.radio_selector.SelectedObject.Text, 'Dibujando una region sobre la figura')
                        % not implemented see #32
                    
                    % total area
                    elseif strcmp(app.radio_selector.SelectedObject.Text, 'Calcular el area de toda la figura')
                        % delete or hide panel elements
                        delete(app.radio_selector.Children)
                        delete(app.radio_selector)
                        app.selector.Visible = 'off';
                        app.selector_label.Visible = 'off';
                        app.next.Visible = 'off';
                        app.previous.Visible = 'off';
                        focus(app.UIFigure)

                        % create slider for alpha radiuses
                        format shortG                 
                        app.alpha_slider = uislider(app.panel, "Position", [125 490 350 200]);
                        app.alpha_slider.MinorTicks = [];
                        alpha_min = app.measureObject.alpha_range(1);
                        alpha_max = app.measureObject.alpha_range(2);
                        app.alpha_slider.Limits = [alpha_min, alpha_max];
                        app.alpha_slider.Value = alpha_min;
                        app.alpha_slider.ValueChangedFcn = @(src,event)updateSlider(app, event);

                        app.alpha_slider_label = uilabel(app.panel, ...
                            "Text", "Mueva el slider para ajustar la concavidad/convexidad del solido asociado a la nube de puntos. Asegurese de que no queden triangulos al interior del solido, de lo contrario las mediciones se veran alteradas.", ...
                            "Position", [60 505 490 150], "WordWrap","on", "FontSize", 14);

                        % % add button for new measure/results
                        app.get_area_button = uibutton(app.panel, 'push');
                        app.get_area_button.Text = 'Obtener resultados y hacer una nueva medicion';
                        app.get_area_button.FontSize = 14;
                        app.get_area_button.Position = [113 375 370 30];
                        app.get_area_button.ButtonPushedFcn = createCallbackFcn(app, @(app, ~) onGetAreaPushed(app), true);
                     
                    end
                end
            catch ME
                fprintf(2, 'Error:\n%s\n', getReport(ME, 'extended', 'hyperlinks', 'on'));
                uialert(app.UIFigure, ME.message, 'Error', 'Icon', 'error');
                delete(app.UIFigure);
            end
        end

        function onPreviousPushed(app, ~)
            if ismember(app.state, {'volumen', 'area', 'longitud'})
                delete(app.radio_selector.Children)
                delete(app.radio_selector)
                app.selector.Enable = 'on';
                app.next.Enable = 'on';
                app.previous.Enable = 'off';
                app.state = 'dropdown';
            end
        end

        function onNewMeasurePushed(app, ~)
            delete(app.results_label)
            delete(app.new_measure)
            delete(app.plot_axis.Children)
            app.selector.Enable = 'on';
            app.selector.Visible = 'on';
            app.next.Enable = 'on';
            app.next.Visible = 'on';
            app.previous.Enable = 'off';
            app.previous.Visible = 'on';
            app.measureObject.plot(app.plot_axis, 'hold',true)
            app.state = 'dropdown';
        end

        function onGetAreaPushed(app, ~)
            area = app.measureObject.areaTotal();
            % show results
            ans = uiconfirm(app.UIFigure, ['La superficie del modelo tiene un area de ' num2str(area) ' unidades'], "Resultados", ...
                            "Options","Nueva medicion", "Icon","info");

            % go to initial state to make another measure
            delete(app.get_area_button)
            delete(app.plot_axis.Children)
            delete(app.alpha_slider)
            delete(app.alpha_slider_label)
            app.selector.Enable = 'on';
            app.selector.Visible = 'on';
            app.next.Enable = 'on';
            app.next.Visible = 'on';
            app.previous.Enable = 'off';
            app.previous.Visible = 'on';
            app.measureObject.plot(app.plot_axis, 'hold',true)
            app.state = 'dropdown';
        end

        function onGetVolumePushed(app, ~)
            volume = app.measureObject.volumeTotal();
            % show results
            ans = uiconfirm(app.UIFigure, ['El modelo tiene un volumen de ' num2str(volume) ' unidades'], "Resultados", ...
                            "Options","Nueva medicion", "Icon","info");

            % go to initial state to make another measure
            delete(app.get_volume_button)
            delete(app.plot_axis.Children)
            delete(app.alpha_slider)
            delete(app.alpha_slider_label)
            app.selector.Enable = 'on';
            app.selector.Visible = 'on';
            app.next.Enable = 'on';
            app.next.Visible = 'on';
            app.previous.Enable = 'off';
            app.previous.Visible = 'on';
            app.measureObject.plot(app.plot_axis, 'hold',true)
            app.state = 'dropdown';
        end

        function onGetRegionPushed(app, callback)
            % delete alpha shape
            delete(app.plot_axis.Children(1))
            % delete panel elements
            delete(app.get_region_button)
            delete(app.alpha_slider)
            delete(app.alpha_slider_label)

            % informs user
            ans = uiconfirm(app.UIFigure, ...
                "Ubique la region con el mouse. Puede estirar el cubo arrastrando cualquiera de las caras. Cuando halla finalizado presione el boton", ...
                "Seleccione la region", ...
                "Options","OK", "Icon","info");

            roi = drawcuboid(app.plot_axis);

            % add button for new measure/results
            app.get_volume_region_button = uibutton(app.panel, 'push');
            app.get_volume_region_button.Text = 'Obtener resultados y hacer una nueva medicion';
            app.get_volume_region_button.Position = [185 100 230 30];
            app.get_volume_region_button.ButtonPushedFcn = createCallbackFcn(app, @(app, ~) callback(app, roi), true);
        end

        function onGetVolumeRegionPushed(app, roi)
            import utils.removePointsOutsideCube
            import utils.sampleCubeFacetsPoints

            inside_cube_points = removePointsOutsideCube(app.measureObject.points, roi.Position);

            cube_points = sampleCubeFacetsPoints(roi.Position, 10);
            inside_shape = inShape(app.measureObject.alpha_complex, cube_points);

            new_shp = alphaShape([inside_cube_points; cube_points(inside_shape, :)]);
            if isempty(new_shp.Points)
                result = 0;
            else
                new_shp.Alpha = app.measureObject.alpha_complex.Alpha;
                [tri, pts] = boundaryFacets(new_shp);
                h = trisurf(tri, pts(:,1), pts(:,2), pts(:,3), 'Parent', app.plot_axis);
                h.FaceColor = [0 0.4470 0.7410]; % blue
                h.FaceAlpha = 0.6;
                result = volume(new_shp);
            end

            % show results
            ans = uiconfirm(app.UIFigure, ['La region tiene un volumen de ' num2str(result) ' unidades'], "Resultados", ...
                            "Options","Nueva medicion", "Icon","info");
            % go to initial state to make another measure
            delete(app.get_volume_region_button)
            delete(app.plot_axis.Children)
            app.selector.Enable = 'on';
            app.selector.Visible = 'on';
            app.next.Enable = 'on';
            app.next.Visible = 'on';
            app.previous.Enable = 'off';
            app.previous.Visible = 'on';
            app.measureObject.plot(app.plot_axis, 'hold',true)
            app.state = 'dropdown';
            
        end

        function onGetAreaRegionPushed(app, roi)
            import utils.removePointsOutsideCube
            import utils.sampleCubeFacetsPoints

            inside_cube_points = removePointsOutsideCube(app.measureObject.points, roi.Position);

            cube_points = sampleCubeFacetsPoints(roi.Position, 10);
            inside_shape = inShape(app.measureObject.alpha_complex, cube_points);

            new_shp = alphaShape([inside_cube_points; cube_points(inside_shape, :)]);
            if isempty(new_shp.Points)
                result = 0;
            else
                new_shp.Alpha = app.measureObject.alpha_complex.Alpha;
                [tri, pts] = boundaryFacets(new_shp);
                h = trisurf(tri, pts(:,1), pts(:,2), pts(:,3), 'Parent', app.plot_axis);
                h.FaceColor = [0 0.4470 0.7410]; % blue
                h.FaceAlpha = 0.6;
                result = surfaceArea(new_shp);
            end

            % show results
            ans = uiconfirm(app.UIFigure, ['La region tiene un area de ' num2str(result) ' unidades'], "Resultados", ...
                            "Options","Nueva medicion", "Icon","info");
            % go to initial state to make another measure
            delete(app.get_volume_region_button)
            delete(app.plot_axis.Children)
            app.selector.Enable = 'on';
            app.selector.Visible = 'on';
            app.next.Enable = 'on';
            app.next.Visible = 'on';
            app.previous.Enable = 'off';
            app.previous.Visible = 'on';
            app.measureObject.plot(app.plot_axis, 'hold',true)
            app.state = 'dropdown';
            
        end

        function updateSlider(app, event)
            % if there is an alpha shape already ploted
            if size(app.plot_axis.Children,1) > 1
                delete(app.plot_axis.Children(1)) % new plots get on first position
            end

            % plot alpha shape
            alpha = event.Value;
            app.measureObject.setAlphaComplex(alpha);
            shp = app.measureObject.alpha_complex;
            [tri, pts] = boundaryFacets(shp);
            h = trisurf(tri, pts(:,1), pts(:,2), pts(:,3), 'Parent', app.plot_axis);
            h.FaceColor = [0 0.4470 0.7410]; % blue
            h.FaceAlpha = 0.6;
        end

        function onButtonDown(app)
            app.resumeSource = 'click';
            uiresume(app.UIFigure);
        end

        function onKeyPress(app)
            app.resumeSource = 'keypress';
            uiresume(app.UIFigure);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SubirarchivoButton
        function SubirarchivoButtonPushed(app, event)
            % Clear any existing layout in the figure
            [file,location] = uigetfile("*.asc");
            drawnow;
            figure(app.UIFigure)
            if file == 0
                return
            end
            
            try
                app.measureObject = Object3D(strcat(location,file));
            catch ME
                uialert(app.UIFigure,ME.message,"No se pudo cargar el modelo 3D","Interpreter","html");
                return
            end
            delete(app.UIFigure.Children);
            
            % Create a new grid layout with two columns
            app.grid = uigridlayout(app.UIFigure, [1, 2]);
            app.grid.RowHeight = {'1x'}; % 1st row flexible, 2nd row fits content
            app.grid.ColumnWidth = {'2x', '1x'}; % Left column wider than right
            app.grid.ColumnSpacing = 10;
            app.grid.Padding = [50 50 50 50];
        
            % Add an axis to the left column
            app.plot_axis = uiaxes(app.grid);
            app.plot_axis.Layout.Row = 1;
            app.plot_axis.Layout.Column = 1;

            app.measureObject.plot(app.plot_axis, 'hold',true)

            % panel
            app.panel = uipanel(app.grid, "BackgroundColor","white");
            app.panel.BorderType = 'line';
            app.panel.Layout.Row = 1;
            app.panel.Layout.Column = 2;

            
            % Selector label
            app.selector_label = uilabel(app.panel);
            app.selector_label.Text = "Seleccione la medida a realizar:";
            app.selector_label.FontSize = 14;
            app.selector_label.Position = [60 725 490 30];
        
            % Add a dropdown to the right column, first row
            app.selector = uidropdown(app.panel);
            app.selector.Items = {'Volumen', 'Area', 'Longitud'};
            app.selector.Position = [60 695 490 30];

            % Add 'Next' button
            app.next = uibutton(app.panel, 'push');
            app.next.Text = 'Siguiente';
            app.next.Position = [310 100 230 30];
            app.next.ButtonPushedFcn = createCallbackFcn(app, @onNextPushed, true);
             
            % % Add 'Previous' button
            app.previous = uibutton(app.panel, 'push');
            app.previous.Text = 'Anterior';
            app.previous.Position = [60 100 230 30];
            app.previous.ButtonPushedFcn = createCallbackFcn(app, @onPreviousPushed, true);
            app.previous.Enable = 'off';
            app.state = 'dropdown';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SubirarchivoButton = uibutton(app.UIFigure, 'push');
            app.SubirarchivoButton.ButtonPushedFcn = createCallbackFcn(app, @SubirarchivoButtonPushed, true);
            app.SubirarchivoButton.Text = 'Subir archivo';
            app.Subeunarchivoconlanubedepuntosdetumodelo3dLabel = uilabel(app.UIFigure);
            app.Subeunarchivoconlanubedepuntosdetumodelo3dLabel.Text = 'Sube un archivo con la nube de puntos de tu modelo 3d';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end